classdef qlgui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        tag_qlgui             matlab.ui.Figure
        uipanel7              matlab.ui.container.Panel
        text29                matlab.ui.control.Label
        text6                 matlab.ui.control.Label
        text7                 matlab.ui.control.Label
        tag_meas_num          matlab.ui.control.DropDown
        text8                 matlab.ui.control.Label
        tag_meas_time         matlab.ui.control.EditField
        text21                matlab.ui.control.Label
        tag_meas_len          matlab.ui.control.DropDown
        checkbox25            matlab.ui.control.CheckBox
        pushbutton7           matlab.ui.control.Button
        tag_meas_num_disp     matlab.ui.control.EditField
        text23                matlab.ui.control.Label
        text24                matlab.ui.control.Label
        tag_aa                matlab.ui.control.StateButton
        tag_windowing         matlab.ui.control.DropDown
        tag_meas_rate         matlab.ui.control.DropDown
        uipanel6              matlab.ui.container.Panel
        text30                matlab.ui.control.Label
        text25                matlab.ui.control.Label
        tag_chan_offset       matlab.ui.control.Slider
        tag_channel           matlab.ui.control.StateButton
        text12                matlab.ui.control.Label
        tag_chan_offset_disp  matlab.ui.control.EditField
        tag_chan_range        matlab.ui.control.DropDown
        tag_chan_zeroise      matlab.ui.control.Button
        tag_chan_signal       matlab.ui.control.DropDown
        text5                 matlab.ui.control.Label
        tag_inst              matlab.ui.control.Button
        tag_avg               matlab.ui.control.Button
        tag_stop              matlab.ui.control.Button
        tag_exit              matlab.ui.control.Button
        pushbutton5           matlab.ui.control.Button
        tag_ch0               matlab.ui.control.StateButton
        tag_ch1               matlab.ui.control.StateButton
        tag_ch2               matlab.ui.control.StateButton
        tag_ch3               matlab.ui.control.StateButton
        text28                matlab.ui.control.Label
        text31                matlab.ui.control.Label
        tag_ch4               matlab.ui.control.StateButton
        tag_ch5               matlab.ui.control.StateButton
        tag_ch6               matlab.ui.control.StateButton
        tag_ch7               matlab.ui.control.StateButton
        uipanel9              matlab.ui.container.Panel
        text11                matlab.ui.control.Label
        text27                matlab.ui.control.Label
        text10                matlab.ui.control.Label
        tag_trig_relfreq      matlab.ui.control.DropDown
        tag_trig_level        matlab.ui.control.DropDown
        tag_trig_type         matlab.ui.control.DropDown
        text20                matlab.ui.control.Label
        text13                matlab.ui.control.Label
        tag_trigger           matlab.ui.control.StateButton
        tag_trig_preroll      matlab.ui.control.DropDown
        tag_trig_signal       matlab.ui.control.DropDown
        uipanel8              matlab.ui.container.Panel
        text15                matlab.ui.control.Label
        text14                matlab.ui.control.Label
        tag_scope_type        matlab.ui.control.DropDown
        tag_scope_adc_ch0     matlab.ui.control.CheckBox
        tag_scope_adc_ch1     matlab.ui.control.CheckBox
        tag_scope_adc_ch2     matlab.ui.control.CheckBox
        tag_scope_adc_ch3     matlab.ui.control.CheckBox
        tag_scope_id          matlab.ui.control.DropDown
        tag_scope_mem         matlab.ui.control.StateButton
        text16                matlab.ui.control.Label
        tag_scope_enc_ch0     matlab.ui.control.CheckBox
        tag_scope_enc_ch1     matlab.ui.control.CheckBox
        tag_scope_enc_ch2     matlab.ui.control.CheckBox
        tag_scope_enc_ch3     matlab.ui.control.CheckBox
        tag_scope_freeze      matlab.ui.control.StateButton
        tag_scope_adc_ch4     matlab.ui.control.CheckBox
        tag_scope_adc_ch5     matlab.ui.control.CheckBox
        tag_scope_enc_ch4     matlab.ui.control.CheckBox
        tag_scope_enc_ch5     matlab.ui.control.CheckBox
        tag_scope_adc_ch6     matlab.ui.control.CheckBox
        tag_scope_adc_ch7     matlab.ui.control.CheckBox
        tag_scope_enc_ch6     matlab.ui.control.CheckBox
        tag_scope_enc_ch7     matlab.ui.control.CheckBox
        text32                matlab.ui.control.Label
        text33                matlab.ui.control.Label
        tag_scope_scale       matlab.ui.control.DropDown
        uipanel10             matlab.ui.container.Panel
        pushbutton13          matlab.ui.control.Button
        pushbutton14          matlab.ui.control.Button
        TextAreaLabel         matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        function is_aqi = current_chan_is_aqi(app)
            
            global g;
            
            ilink=get_link_id(app, g.QS_CHAN_SIGNAL);
            
            if g.QS_AQI(ilink+1)==1
                is_aqi=1;
            else
                is_aqi=0;
            end
        end
        
        function ilink = get_link_id(app, ichan)
            % ichan starts from 1
            
            global g;
            
            % copy channel id
            i=ichan;
            
            % correct channel id in case of encoder signal
            if i>g.QS_MAXCHAN
                i=i-g.QS_MAXCHAN;
            end
            
            ilink=floor((i-1)/2);
        end
        
        function sync_aliasing(app, h, fs, iconfig)
            
            global g;
            
            def=qs_defines;
            
            for i=1:g.QS_NDEVS,
                if (g.QS_AQI(i)==1)
                    if iconfig==0
                        tde_gate_m(def.CALL_TD_AAFILTER_OFF,i-1);
                    else
                        tde_gate_m(def.CALL_TD_AAFILTER_ON,fs/2.5,iconfig,i-1);
                    end
                end
            end
            
            gupdate;
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.tag_qlgui.DeleteFcn = createCallbackFcn(app, @tag_qlgui_DeleteFcn, true);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: tag_aa
        function cb_antialiasing(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            % adapt aa-filter config
            g.QS_AACONFIG=g.QS_AACONFIG+1;
            if g.QS_AACONFIG>2
                g.QS_AACONFIG=0;
            end
            
            g.QS_MEAS_RATE=get(handles.tag_meas_rate,'Value');
            sync_aliasing(app, handles.tag_aa,g.QS_MSAMPRATE(g.QS_MEAS_RATE),g.QS_AACONFIG);
        end

        % Button pushed function: tag_avg
        function cb_avg(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_IFRAME=1;
            g.QS_AVG_ON=1;
            
            reset_trigger;
            
            turnon;
            
            gupdate;
        end

        % Value changed function: tag_chan_offset
        function cb_chan_offset(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Offset function for offset slider
            
            global g;
            
            def=qs_defines;
            
            offset=get(handles.tag_chan_offset,'Value');
            tde_gate_m(def.CALL_TDE_SET_ADC_OFFSET,offset,g.QS_CHAN_SIGNAL-1);
            g.QS_CHAN_IN_OFFSET(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ADC_OFFSET,g.QS_CHAN_SIGNAL-1);
            
            gupdate;
        end

        % Value changed function: tag_chan_offset_disp
        function cb_chan_offset2(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            offset=str2num(get(handles.tag_chan_offset_disp,'String'));
            
            current_range=g.QS_CHAN_IN_RANGE(g.QS_CHAN_SIGNAL);
            offset=max(-g.QS_MAXLEV(current_range),min(g.QS_MAXLEV(current_range),offset))
            tde_gate_m(def.CALL_TDE_SET_ADC_OFFSET,offset,g.QS_CHAN_SIGNAL-1);
            g.QS_CHAN_IN_OFFSET(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ADC_OFFSET,g.QS_CHAN_SIGNAL-1);
            
            gupdate;
        end

        % Value changed function: tag_chan_range
        function cb_chan_range(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_CHAN_SIGNAL=get(handles.tag_chan_signal,'Value'); % value goes from 1 to 9, starting with +/- 10V
            % fprintf('range = %d\n', get(handles.tag_chan_range, 'Value'));
            % set range
            if g.QS_CHAN_SIGNAL<=g.QS_MAXCHAN
                ilink=get_link_id(app, g.QS_CHAN_SIGNAL);
                ioff=2*ilink;
                irange=get(handles.tag_chan_range,'Value');
                tde_gate_m(def.CALL_TD_SET_RANGE,irange-1,g.QS_CHAN_SIGNAL-1-ioff,ilink);
                g.QS_CHAN_IN_RANGE(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TD_GET_RANGE,g.QS_CHAN_SIGNAL-1-ioff,ilink)+1;
            %   adapt offset to range
                offset=str2num(get(handles.tag_chan_offset_disp,'String'));
                current_range=g.QS_CHAN_IN_RANGE(g.QS_CHAN_SIGNAL);
                offset=max(-g.QS_MAXLEV(current_range),min(g.QS_MAXLEV(current_range),offset));
                tde_gate_m(def.CALL_TDE_SET_ADC_OFFSET,offset,g.QS_CHAN_SIGNAL-1);
                g.QS_CHAN_IN_OFFSET(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ADC_OFFSET,g.QS_CHAN_SIGNAL-1);
            end
            
            reset_trigger;
            
            gupdate;
        end

        % Value changed function: tag_chan_signal
        function cb_chan_signal(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_CHAN_SIGNAL=get(handles.tag_chan_signal,'Value');
            
            % correct chan id for number of TUeDACS devices
            if g.QS_CHAN_SIGNAL>2*g.QS_NDEVS
                g.QS_CHAN_SIGNAL=g.QS_CHAN_SIGNAL+2*(def.MAXDEVS-g.QS_NDEVS);
            end
            
            if g.QS_CHAN_SIGNAL<=g.QS_MAXCHAN
            %   selected channel is of type adc
                g.QS_CHAN_IN_STATE(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ADC_STATE,g.QS_CHAN_SIGNAL-1);
                g.QS_CHAN_IN_OFFSET(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ADC_OFFSET,g.QS_CHAN_SIGNAL-1);
            else
            %   selected channel is of type enc
                g.QS_CHAN_IN_STATE(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ENC_STATE,g.QS_CHAN_SIGNAL-1-g.QS_MAXCHAN);
            end
            
            % get current device
            g.QS_CURRENT_DEVICE=get_link_id(app, g.QS_CHAN_SIGNAL)+1;
            
            gupdate;
        end

        % Button pushed function: tag_chan_zeroise
        function cb_chan_zeroise(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            if g.QS_CHAN_SIGNAL<=g.QS_MAXCHAN
                offset=0.0;
                tde_gate_m(def.CALL_TDE_SET_ADC_OFFSET,offset,g.QS_CHAN_SIGNAL-1);
                g.QS_CHAN_IN_OFFSET(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ADC_OFFSET,g.QS_CHAN_SIGNAL-1);
            else
            %   if g.QS_IS_SIMULINK~=1
                tde_gate_m(def.CALL_TDE_INIT_ENC,g.QS_CHAN_SIGNAL-1-g.QS_MAXCHAN);
            %   end
            end
            
            gupdate;
        end

        % Value changed function: tag_channel
        function cb_channel(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            istate=get(handles.tag_channel,'Value');
            
            if g.QS_CHAN_SIGNAL<=g.QS_MAXCHAN
                tde_gate_m(def.CALL_TDE_SET_ADC_STATE,istate,g.QS_CHAN_SIGNAL-1);
                g.QS_CHAN_IN_STATE(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ADC_STATE,g.QS_CHAN_SIGNAL-1);
            else
                tde_gate_m(def.CALL_TDE_SET_ENC_STATE,istate,g.QS_CHAN_SIGNAL-1-g.QS_MAXCHAN);
                g.QS_CHAN_IN_STATE(g.QS_CHAN_SIGNAL)=tde_gate_m(def.CALL_TDE_GET_ENC_STATE,g.QS_CHAN_SIGNAL-1-g.QS_MAXCHAN);
            end
            
            if g.QS_CHAN_SIGNAL<=g.QS_MAXCHAN
                for i=1:g.QS_MAXSCOPE,
                    g.QS_SCOPE_CHAN(i,g.QS_CHAN_SIGNAL)=0;
                end
            else
                for i=1:g.QS_MAXSCOPE,
                    g.QS_SCOPE_CHAN(i,g.QS_CHAN_SIGNAL)=0;
                end
            end
            
            gupdate;
            
            % redraw
            g.QS_REDRAW=1;
        end

        % Button pushed function: tag_exit
        function cb_exit(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            g.QS_EXIT=1;
        end

        % Button pushed function: pushbutton5
        function cb_fun_gen(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            qlgen;
        end

        % Button pushed function: tag_inst
        function cb_inst(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_IFRAME=1;
            g.QS_AVG_ON=0;
            
            reset_trigger;
            
            turnon;
            
            gupdate;
        end

        % Button pushed function: pushbutton14
        function cb_load(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            % stop measurement before loading
            turnoff;
            g.QS_IFRAME=1;
            
            % close generator window if open (because gen save has not been implemented yet!)
            if g.QSG_GEN_OPEN
                close(g.QSG_FIG);
                g.QSG_GEN_OPEN=0;
            end
            
            % backup globals
            bak_g=g;
            
            % ask file name
            [filename,pathname]=uigetfile('*.qss', 'Load');
            
            if filename~=0
            %   store present path
                present_path=cd;
            
            %   change path and read file
                eval(sprintf('cd %s',pathname));
                if filename~=0
                    eval(sprintf('load -mat %s',filename));
                end
            
            %   restore path
                eval(sprintf('cd %s',present_path));
            
            %   consistency check
                ok=1;
                if g.QS_NDEVS~=bak_g.QS_NDEVS
            	ok=0;
                end
            
                for i=1:def.MAXDEVS,
            	if g.QS_AQI(i)~=bak_g.QS_AQI(i)
            	    ok=0;
            	end
                end
            
                if ~ok
            	msgbox('File not consistent with current hardware configuration!');
            %	restore globals
            	g=bak_g;
                else
            %	no generator window
            	g.QSG_GEN_OPEN=0;
            
            %   	overwrite restored MAXFREQ with current one!
            	g.QS_MAXFREQ=bak_g.QS_MAXFREQ;
            
            %   	synchronize hardware with restored settings
            	for i=1:2*g.QS_NDEVS,
            	    tde_gate_m(def.CALL_TDE_SET_ADC_STATE,g.QS_CHAN_IN_STATE(i),i-1);
                	    g.QS_CHAN_IN_STATE(i)=tde_gate_m(def.CALL_TDE_GET_ADC_STATE,i-1);
            	end
            	for i=1:2*g.QS_NDEVS,
                	    tde_gate_m(def.CALL_TDE_SET_ENC_STATE,g.QS_CHAN_IN_STATE(g.QS_MAXCHAN+i),i-1);
            	    g.QS_CHAN_IN_STATE(g.QS_MAXCHAN+i)=tde_gate_m(def.CALL_TDE_GET_ENC_STATE,i-1);
            	end
            %	for i=1:2*g.QS_NDEVS,
            %    	    tde_gate_m(def.CALL_TDE_SET_DAC_STATE,g.QS_CHAN_OUT_STATE(i),i-1);
            %	    g.QS_CHAN_OUT_STATE(i)=tde_gate_m(def.CALL_TDE_GET_DAC_STATE,i-1);
            %	end
            	g.QS_CHAN_OUT_STATE=bak_g.QS_CHAN_OUT_STATE;
            %   	offsets
            	for i=1:2*g.QS_NDEVS,
                        tde_gate_m(def.CALL_TDE_SET_ADC_OFFSET,g.QS_CHAN_IN_OFFSET(i),i-1);
            	    g.QS_CHAN_IN_OFFSET(i)=tde_gate_m(def.CALL_TDE_GET_ADC_OFFSET,i-1);
            	end
            	for i=1:2*g.QS_NDEVS,,
            %           tde_gate_m(def.CALL_TDE_SET_DAC_OFFSET,g.QS_CHAN_OUT_OFFSET(i),i-1);
            %	    g.QS_CHAN_OUT_OFFSET(i)=tde_gate_m(def.CALL_TDE_GET_DAC_OFFSET,i-1);
            	end
            	g.QS_CHAN_OUT_OFFSET=bak_g.QS_CHAN_OUT_OFFSET;
            %   	range of adc channels
            	for i=1:2*g.QS_NDEVS,
            	    ilink=get_link_id(app, g.QS_CHAN_SIGNAL);
            	    if g.QS_AQI(ilink+1)==1
            		tde_gate_m(def.CALL_TD_SET_RANGE,g.QS_CHAN_IN_RANGE(i)-1,g.QS_CHAN_SIGNAL-1,0);
                		g.QS_CHAN_IN_RANGE(i)=tde_gate_m(def.CALL_TD_GET_RANGE,g.QS_CHAN_SIGNAL-1,0)+1;
            %		sync filter settings
            		sync_aliasing(app, handles.tag_aa,g.QS_MSAMPRATE(g.QS_MEAS_RATE),g.QS_AACONFIG);
            	    end
            	end
            
            %	write sampling rate to engine
            	tde_gate_m(def.CALL_TDE_SET_NDOWN,g.QS_NDOWN);
            
            %	write number of samples to engine
            	tde_gate_m(def.CALL_TDE_SET_NSAMP,g.QS_NSAMP);
            
            %	reset trigger
            	reset_trigger;
                end
            end
            
            gupdate;
            
            g.QS_REDRAW=1;
            g.QS_IFRAME=1;
            g.QS_VALID=0;
        end

        % Value changed function: tag_meas_len
        function cb_meas_len(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            if g.QS_AVG_ON==0
            %   pause measurement if necessary
                if g.QS_RUNNING==1
                    tde_gate_m(def.CALL_TDE_PAUSE,1);
                end
            else
                turnoff;
                g.QS_IFRAME=1;
            end
            
            g.QS_MEAS_LEN=get(handles.tag_meas_len,'Value');
            g.QS_NSAMP=min(g.QS_MFRAMELEN(g.QS_MEAS_LEN),g.QS_MAXFRAME);
            tde_gate_m(def.CALL_TDE_SET_NSAMP,g.QS_NSAMP);
            
            % update gen frequency settings before restarting measurement!
            sync_gen_frequency_settings;
            
            if g.QS_AVG_ON==0
            %   continue measurement
                if g.QS_RUNNING==1
                    tde_gate_m(def.CALL_TDE_PAUSE,0);
                end
            end
            
            g.QS_MEAS_TIME=num2str(g.QS_NSAMP/g.QS_MSAMPRATE(g.QS_MEAS_RATE));
            
            g.QS_REDRAW=1;
            g.QS_IFRAME=1;
            g.QS_VALID=0;
            
            gupdate;
        end

        % Value changed function: tag_meas_num
        function cb_meas_num(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            g.QS_MEAS_NUM=get(handles.tag_meas_num,'Value');
            
            turnoff;
            
            g.QS_IFRAME=1;
            g.QS_VALID=0;
            
            gupdate;
        end

        % Value changed function: tag_meas_rate
        function cb_meas_rate(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_MEAS_RATE=get(handles.tag_meas_rate,'Value');
            i=g.QS_MEAS_RATE;
            while (g.QS_MSAMPRATE(i)>g.QS_MAXFREQ) & (i<max(size(g.QS_MSAMPRATE)))
                i=i+1;
            end
            g.QS_MEAS_RATE=i;
            
            g.QS_NDOWN=max(round(g.QS_MAXFREQ/g.QS_MSAMPRATE(g.QS_MEAS_RATE))-1,0);
            
            if g.QS_AVG_ON==0
            %   pause measurement if necessary
                if g.QS_RUNNING==1
                    tde_gate_m(def.CALL_TDE_PAUSE,1);
                end
            else
                turnoff;
            
                g.QS_IFRAME=1;
            end
            
            tde_gate_m(def.CALL_TDE_SET_NDOWN,g.QS_NDOWN);
            
            if g.QS_AACONFIG>0
            %   sync aa-filter settings
                sync_aliasing(app, handles.tag_aa,g.QS_MSAMPRATE(g.QS_MEAS_RATE),g.QS_AACONFIG);
            end
            
            % update gen frequency settings before restarting measurement!
            sync_gen_frequency_settings;
            
            if g.QS_AVG_ON==0
            %   continue measurement
                if g.QS_RUNNING==1
                    tde_gate_m(def.CALL_TDE_PAUSE,0);
                end
            end
            
            g.QS_MEAS_TIME=num2str(g.QS_NSAMP/g.QS_MSAMPRATE(g.QS_MEAS_RATE));
            
            g.QS_REDRAW=1;
            g.QS_IFRAME=1;
            g.QS_VALID=0;
            
            gupdate;
        end

        % Button pushed function: pushbutton13
        function cb_save(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g data;
            
            def=qs_defines;
            
            % stop measurement before saving
            turnoff;
            g.QS_IFRAME=1;
            
            irange=zeros(g.QS_MAXCHAN,1);
            for i=1:g.QS_MAXCHAN,
                ilink=get_link_id(app, i);
                ioff=2*ilink;
            %    if g.QS_IS_SIMULINK==1
            %	irange(i)=1;
            %    else
            	irange(i)=tde_gate_m(def.CALL_TD_GET_RANGE,i-1-ioff,ilink)+1;
            %    end
            end
            
            % ask file name
            [filename,pathname]=uiputfile('*.qss', 'Save');
            
            if filename~=0
            %   check filename for extension
            
                l=max(size(filename));
                isdot=filename(max(1,l-3));
                if strcmp(isdot,'.')
                    filename=filename(1:l-4);
                end
            
            %   store present path
                present_path=cd;
            
            %   change path and write file
                eval(sprintf('cd %s',pathname));
                eval(sprintf('save %s.qss g data',filename));
            
            %   restore path
                eval(sprintf('cd %s',present_path));
            end
            
            gupdate;
        end

        % Button pushed function: pushbutton7
        function cb_save_last(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g data;
            
            def=qs_defines;
            
            % stop measurement before loading
            turnoff;
            g.QS_IFRAME=1;
            
            % ask file name
            [filename,pathname]=uiputfile('*.mat', 'Save');
            
            if filename~=0
            %   check filename for extension
            
                l=max(size(filename));
                isdot=filename(max(1,l-3));
                if strcmp(isdot,'.')
                    filename=filename(1:l-4);
                end
            
            %   store present path
                present_path=cd;
            
            %   change path and write file
                eval(sprintf('cd %s',pathname));
                eval(sprintf('save %s.mat filename',filename));
            end
            
            lfname=filename;
            
            for i=1:g.QS_MAXCHAN*2,
                if g.QS_CHAN_IN_STATE(i)==1
            
                    if i<=g.QS_MAXCHAN
                        prefix='adc';
                        idx=i;
                    else
                        prefix='enc';
                        idx=i-g.QS_MAXCHAN;
                    end
            
            %       save time
                    eval(sprintf('%s_time=data.TIM_XT;',prefix));
                    eval(sprintf('save %s %s_time -append',lfname,prefix));
            
            %       save frame
                    eval(sprintf('%s_ch%d_frame=data.XT(i,:);',prefix,idx-1));
                    eval(sprintf('save %s %s_ch%d_frame -append',lfname,prefix,idx-1));
            
            %       save frequency
                    eval(sprintf('%s_freq_xf=data.FREQ_XF'';',prefix));
                    eval(sprintf('save %s %s_freq_xf -append',lfname,prefix));
            
            %       save dft modulus
                    eval(sprintf('%s_ch%d_xf_mod=data.XF_MOD(i,:);',prefix,idx-1));
                    eval(sprintf('save %s %s_ch%d_xf_mod -append',lfname,prefix,idx-1));
            
            %       save dft phase
                    eval(sprintf('%s_ch%d_xf_phs=data.XF_PHS(i,:);',prefix,idx-1));
                    eval(sprintf('save %s %s_ch%d_xf_phs -append',lfname,prefix,idx-1));
            
            %       save frequency
                    eval(sprintf('%s_freq_psd_auto=data.FREQ_PSD_AUTO'';',prefix));
                    eval(sprintf('save %s %s_freq_psd_auto -append',lfname,prefix));
            
            %       save psd_auto
                    eval(sprintf('%s_ch%d_psd_auto=data.PSD_AUTO(i,:);',prefix,idx-1));
                    eval(sprintf('save %s %s_ch%d_psd_auto -append',lfname,prefix,idx-1));
            
                    if (g.QS_IN==i) & ((g.QS_SCOPE_DOFRF(1)==1 | g.QS_SCOPE_DOFRF(2)==1));
                        if g.QS_OUT<=g.QS_MAXCHAN
                            prefix2='adc';
                            idx2=g.QS_OUT;
                        else
                            prefix2='enc';
                            idx2=g.QS_OUT-g.QS_MAXCHAN;
                        end
            
            %           save frequency
                        freq_frf=data.FREQ_FRF;
                        freq_frf=freq_frf';
                        eval(sprintf('save %s freq_frf -append',lfname));
            
            %           save frf_mod
                        eval(sprintf('%s_ch%d_%s_ch%d_frf_mod=data.FRF_MOD'';',prefix,idx-1,prefix2,idx2-1));
                        eval(sprintf('save %s %s_ch%d_%s_ch%d_frf_mod -append',lfname,prefix,idx-1,prefix2,idx2-1));
            
            %           save frf_phs
                        eval(sprintf('%s_ch%d_%s_ch%d_frf_phs=data.FRF_PHS'';',prefix,idx-1,prefix2,idx2-1));
                        eval(sprintf('save %s %s_ch%d_%s_ch%d_frf_phs -append',lfname,prefix,idx-1,prefix2,idx2-1));
            
            %           save psd_cross
                        eval(sprintf('%s_ch%d_%s_ch%d_psd_cross=data.PSD_CROSS'';',prefix,idx-1,prefix2,idx2-1));
                        eval(sprintf('save %s %s_ch%d_%s_ch%d_psd_cross -append',lfname,prefix,idx-1,prefix2,idx2-1));
            
            %           save coh
                        eval(sprintf('%s_ch%d_%s_ch%d_coherence=data.COH'';',prefix,idx-1,prefix2,idx2-1));
                        eval(sprintf('save %s %s_ch%d_%s_ch%d_coherence -append',lfname,prefix,idx-1,prefix2,idx2-1));
                    end
                end
            end
            
            % restore path
            eval(sprintf('cd %s',present_path));
            
            gupdate;
        end

        % Value changed function: tag_scope_adc_ch0, 
        % tag_scope_adc_ch1, tag_scope_adc_ch2, tag_scope_adc_ch3, 
        % tag_scope_adc_ch4, tag_scope_adc_ch5, tag_scope_adc_ch6, 
        % tag_scope_adc_ch7
        function cb_scope_adc(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            if g.QS_AVG_ON==1
                turnoff;
                g.QS_IFRAME=1;
            end
            
            % get id of selected channel
            the_tag=get(h,'Tag');
            id=str2num(the_tag(17))+1;
            val=get(h,'Value');
            
            g.QS_SCOPE_CHAN(g.QS_SCOPE_ID,id)=val;
            
            switch  g.QS_SCOPE_DOFRF(g.QS_SCOPE_ID);
            case 0
                if val==1
            %	new id becomes input, input becomes output
            	g.QS_OUT=g.QS_IN;
            	g.QS_IN=id;
                end
            case 1
                g.QS_SCOPE_CHAN(g.QS_SCOPE_ID,id)=1;
                switch id
                case g.QS_IN
            %	swap in/out
            	ttt=g.QS_OUT;
            	g.QS_OUT=id;
            	g.QS_IN=ttt;
                case g.QS_OUT
            %	swap in/out
            	ttt=g.QS_IN;
            	g.QS_IN=id;
            	g.QS_OUT=ttt;
                otherwise
            %	selected channel becomes frf input signal
            	g.QS_IN=id;
                end
            end
            
            g.QS_REDRAW=1;
            g.QS_VALID=0;
            
            gupdate;
        end

        % Value changed function: tag_scope_enc_ch0, 
        % tag_scope_enc_ch1, tag_scope_enc_ch2, tag_scope_enc_ch3, 
        % tag_scope_enc_ch4, tag_scope_enc_ch5, tag_scope_enc_ch6, 
        % tag_scope_enc_ch7
        function cb_scope_enc(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            if g.QS_AVG_ON==1
                turnoff;
                g.QS_IFRAME=1;
            end
            
            % get id of selected channel
            the_tag=get(h,'Tag');
            id=str2num(the_tag(17))+1+g.QS_MAXCHAN;
            val=get(h,'Value');
            
            g.QS_SCOPE_CHAN(g.QS_SCOPE_ID,id)=val;
            
            switch  g.QS_SCOPE_DOFRF(g.QS_SCOPE_ID);
            case 0
                if val==1
            %	new id becomes input, input becomes output
            	g.QS_OUT=g.QS_IN;
            	g.QS_IN=id;
                end
            case 1
                g.QS_SCOPE_CHAN(g.QS_SCOPE_ID,id)=1;
                switch id
                case g.QS_IN
            %	swap in/out
            	ttt=g.QS_OUT;
            	g.QS_OUT=id;
            	g.QS_IN=ttt;
                case g.QS_OUT
            %	swap in/out
            	ttt=g.QS_IN;
            	g.QS_IN=id;
            	g.QS_OUT=ttt;
                otherwise
            %	selected channel becomes frf input signal
            	g.QS_IN=id;
                end
            end
            
            g.QS_REDRAW=1;
            g.QS_VALID=0;
            
            gupdate;
        end

        % Value changed function: tag_scope_freeze
        function cb_scope_freeze(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g g2 data;
            
            def=qs_defines;
            
            g.QS_SCOPE_FREEZE(g.QS_SCOPE_ID)=get(handles.tag_scope_freeze,'Value');
            
            j=g.QS_SCOPE_ID;
            if g.QS_SCOPE_FREEZE(j)==1
                g.QS_SCOPE_XBAK(j,1:2)=get(g2.QS_SCOPE_HDLS(j),'Xlim');
                axis(g2.QS_SCOPE_HDLS(g.QS_SCOPE_ID),'manual');
            else
                axis(g2.QS_SCOPE_HDLS(j),'auto');
                g.QS_REDRAW=1;
                if sum(abs(g.QS_SCOPE_XBAK(j,:)))>0
                    set(g2.QS_SCOPE_HDLS(j),'XLim',g.QS_SCOPE_XBAK(j,:));
                end
            end
            
            gupdate;
        end

        % Value changed function: tag_scope_id
        function cb_scope_id(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_SCOPE_ID=get(handles.tag_scope_id,'Value');
            
            gupdate;
        end

        % Value changed function: tag_scope_mem
        function cb_scope_mem(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g data;
            
            def=qs_defines;
            
            g.QS_SCOPE_MEM(g.QS_SCOPE_ID)=get(handles.tag_scope_mem,'Value');
            
            % set scope memory button state
            if g.QS_SCOPE_MEM(g.QS_SCOPE_ID)==1
            %   initialize scope data memory
                lent=max(size(data.TIM_XT));
                lenf1=max(size(data.FREQ_XF));
                lenf2=max(size(data.FREQ_PSD_AUTO));
                lenf3=max(size(data.FREQ_FRF));
            
            %   current scope
                j=g.QS_SCOPE_ID;
            
            %   reset current scope memory
                for i=1:2*g.QS_MAXCHAN,
                    idx=(j-1)*g.QS_MAXCHAN*2+i;
                    g.QS_SCOPE_MEM_IDAT(idx,1)=0;
                end
            
            %   set current scope memory
                for i=1:g.QS_MAXCHAN*2,
                    if g.QS_SCOPE_CHAN(j,i)==1
                        idx=(j-1)*g.QS_MAXCHAN*2+i;
                        g.QS_SCOPE_MEM_IDAT(idx,1)=1;
                        switch g.QS_SCOPE_TYPE(j)
                        case def.QSST_TIME,
                            if g.QS_VALID==1
                                g.QS_SCOPE_MEM_XDAT(j,1:lent)=data.TIM_XT;
                                g.QS_SCOPE_MEM_YDAT(idx,1:lent)=data.XT(i,:);
                                g.QS_SCOPE_MEM_LDAT(j,1)=lent;
                            end
                        case def.QSST_FFT_MOD,
                            if g.QS_VALID==1
                                g.QS_SCOPE_MEM_XDAT(j,1:lenf1)=data.FREQ_XF;
                                g.QS_SCOPE_MEM_YDAT(idx,1:lenf1)=data.XF_MOD(i,:);
                                g.QS_SCOPE_MEM_LDAT(j,1)=lenf1;
                            end
                        case def.QSST_FFT_PHS,
                            if g.QS_VALID==1
                                g.QS_SCOPE_MEM_XDAT(j,1:lenf1)=data.FREQ_XF;
                                g.QS_SCOPE_MEM_YDAT(idx,1:lenf1)=data.XF_PHS(i,:);
                                g.QS_SCOPE_MEM_LDAT(j,1)=lenf1;
                            end
                        case {def.QSST_PSD_AUTO,def.QSST_ESD_AUTO,def.QSST_PSD_AUTO_CUM,def.QSST_POW_AUTO}
                            if g.QS_VALID==1
                                g.QS_SCOPE_MEM_XDAT(j,1:lenf2)=data.FREQ_PSD_AUTO;
                                g.QS_SCOPE_MEM_YDAT(idx,1:lenf2)=data.PSD_AUTO(i,:);
                                g.QS_SCOPE_MEM_LDAT(j,1)=lenf2;
                            end
                        case def.QSST_PSD_CROSS,
                            if i==g.QS_IN
                                if g.QS_VALID==1
                                    g.QS_SCOPE_MEM_XDAT(j,1:lenf3)=data.FREQ_FRF;
                                    g.QS_SCOPE_MEM_YDAT(idx,1:lenf3)=data.PSD_CROSS';
                                    g.QS_SCOPE_MEM_LDAT(j,1)=lenf3;
                                end
                             end
                        case {def.QSST_FRF_MOD}
                            if i==g.QS_IN
                                if g.QS_VALID==1
                                    g.QS_SCOPE_MEM_XDAT(j,1:lenf3)=data.FREQ_FRF;
                                    g.QS_SCOPE_MEM_YDAT(idx,1:lenf3)=data.FRF_MOD';
                                    g.QS_SCOPE_MEM_LDAT(j,1)=lenf3;
                                end
                            end
                        case {def.QSST_FRF_PHS}
                            if i==g.QS_IN
                                if g.QS_VALID==1
                                    g.QS_SCOPE_MEM_XDAT(j,1:lenf3)=data.FREQ_FRF;
                                    g.QS_SCOPE_MEM_YDAT(idx,1:lenf3)=data.FRF_PHS';
                                    g.QS_SCOPE_MEM_LDAT(j,1)=lenf3;
                                end
                            end
                        case def.QSST_COHERENCE,
                            if i==g.QS_IN
                                if g.QS_VALID==1
                                    g.QS_SCOPE_MEM_XDAT(j,1:lenf3)=data.FREQ_FRF;
                                    g.QS_SCOPE_MEM_YDAT(idx,1:lenf3)=data.COH';
                                    g.QS_SCOPE_MEM_LDAT(j,1)=lenf3;
                                end
                            end
                        case {def.QSST_FRF_OL_MOD}
                            if i==g.QS_IN
                                if g.QS_VALID==1
                                    g.QS_SCOPE_MEM_XDAT(j,1:lenf3)=data.FREQ_FRF;
                                    g.QS_SCOPE_MEM_YDAT(idx,1:lenf3)=data.FRF_OL_MOD';
                                    g.QS_SCOPE_MEM_LDAT(j,1)=lenf3;
                                end
                            end
                        case {def.QSST_FRF_OL_PHS}
                            if i==g.QS_IN
                                if g.QS_VALID==1
                                    g.QS_SCOPE_MEM_XDAT(j,1:lenf3)=data.FREQ_FRF;
                                    g.QS_SCOPE_MEM_YDAT(idx,1:lenf3)=data.FRF_OL_PHS';
                                    g.QS_SCOPE_MEM_LDAT(j,1)=lenf3;
                                end
                            end
                        case {def.QSST_FRF_OL_NYQ}
                            if i==g.QS_IN
                                if g.QS_VALID==1
                                    g.QS_SCOPE_MEM_XDAT(j,1:lenf3)=real(data.FRF_OL)';
                                    g.QS_SCOPE_MEM_YDAT(idx,1:lenf3)=imag(data.FRF_OL)';
                                    g.QS_SCOPE_MEM_LDAT(j,1)=lenf3;
                                end
                            end
                        otherwise,
                        end
                    end
                end
            end
            
            % redraw
            g.QS_REDRAW=1;
            
            gupdate;
        end

        % Value changed function: tag_scope_scale
        function cb_scope_scale(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            switch g.QS_SCOPE_TYPE(g.QS_SCOPE_ID)
            case {def.QSST_ESD_AUTO,def.QSST_PSD_AUTO, def.QSST_POW_AUTO,def.QSST_PSD_AUTO_CUM, def.QSST_PSD_CROSS, def.QSST_FRF_MOD, def.QSST_FRF_PHS, def.QSST_COHERENCE, def.QSST_FRF_OL_MOD, def.QSST_FRF_OL_PHS},
                g.QS_SCOPE_SCALE(g.QS_SCOPE_ID)=get(handles.tag_scope_scale,'Value');
            otherwise,
                g.QS_SCOPE_SCALE(g.QS_SCOPE_ID)=def.QSSS_LIN;
                set(handles.tag_scope_scale,'Value',g.QS_SCOPE_SCALE(g.QS_SCOPE_ID));
            end
            
            g.QS_REDRAW=1;
            
            gupdate;
        end

        % Value changed function: tag_scope_type
        function cb_scope_type(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            old_type=g.QS_SCOPE_TYPE(g.QS_SCOPE_ID);
            new_type=get(handles.tag_scope_type,'Value');
            
            % check if frf measurement is requested
            switch new_type
            case {def.QSST_PSD_CROSS, def.QSST_FRF_MOD, def.QSST_FRF_PHS, def.QSST_COHERENCE, def.QSST_FRF_OL_MOD, def.QSST_FRF_OL_PHS, def.QSST_FRF_OL_NYQ},
                if g.QS_CHAN_IN_STATE(g.QS_IN) & g.QS_CHAN_IN_STATE(g.QS_OUT) & (g.QS_IN~=g.QS_OUT)
            %	set frf mode
                    g.QS_SCOPE_DOFRF(g.QS_SCOPE_ID)=1;
            
            %	activate scope channels
            	g.QS_SCOPE_CHAN(g.QS_SCOPE_ID,g.QS_IN)=1;
                    g.QS_SCOPE_CHAN(g.QS_SCOPE_ID,g.QS_OUT)=1;
            
            	g.QS_SCOPE_TYPE(g.QS_SCOPE_ID)=new_type;
            %	sync other scope if of frf type
            	other_scope_id=-g.QS_SCOPE_ID+3;
            	if g.QS_SCOPE_DOFRF(other_scope_id)
            	    g.QS_SCOPE_CHAN(other_scope_id,g.QS_IN)=1;
            	    g.QS_SCOPE_CHAN(other_scope_id,g.QS_OUT)=1;
            	end
                end
            otherwise
                g.QS_SCOPE_DOFRF(g.QS_SCOPE_ID)=0;
                g.QS_SCOPE_TYPE(g.QS_SCOPE_ID)=new_type;
            end
            
            % check if measurement should be stopped
            switch old_type
            case {def.QSST_TIME,def.QSST_FFT_MOD,def.QSST_FFT_PHS,def.QSST_PSD_AUTO}
                switch new_type
                case {def.QSST_TIME,def.QSST_FFT_MOD,def.QSST_FFT_PHS,def.QSST_PSD_AUTO}
            %       new_type and old_type are from the same class
                otherwise,
            %       new_type and old_type are not from the same class
                    if g.QS_AVG_ON==1
                        turnoff;
                        g.QS_IFRAME=1;
                    end
                end
            otherwise,
                switch new_type
                case {def.QSST_TIME,def.QSST_FFT_MOD,def.QSST_FFT_PHS,def.QSST_PSD_AUTO}
            %       new_type and old_type are not from the same class
                    if g.QS_AVG_ON==1
                        turnoff;
                        g.QS_IFRAME=1;
                    end
                otherwise,
            %       new_type and old_type are from the same class
                end
            end
            
            % g.QS_SCOPE_TYPE(g.QS_SCOPE_ID)=get(handles.tag_scope_type,'Value');
            switch g.QS_SCOPE_TYPE(g.QS_SCOPE_ID)
            case {def.QSST_TIME, def.QSST_FFT_MOD, def.QSST_FFT_PHS},
                g.QS_SCOPE_SCALE(g.QS_SCOPE_ID)=def.QSSS_LIN;
            end
            
            % reset scope mem
            g.QS_SCOPE_MEM(g.QS_SCOPE_ID)=0;
            
            % reset scope freeze
            g.QS_SCOPE_FREEZE(g.QS_SCOPE_ID)=0;
            
            g.QS_REDRAW=1;
            
            gupdate;
        end

        % Button pushed function: tag_stop
        function cb_stop(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            turnoff;
            
            g.QS_IFRAME=1;
            
            gupdate;
        end

        % Value changed function: tag_trig_level
        function cb_trig_level(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_TRIG_LEVEL=get(handles.tag_trig_level,'Value');
            
            reset_trigger;
            
            gupdate;
        end

        % Value changed function: tag_trig_preroll
        function cb_trig_preroll(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_TRIG_PREROLL=get(handles.tag_trig_preroll,'Value');
            
            reset_trigger;
            
            gupdate;
        end

        % Value changed function: tag_trig_relfreq
        function cb_trig_relfreq(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_TRIG_RELFREQ=get(handles.tag_trig_relfreq,'Value');
            
            reset_trigger;
            
            gupdate;
        end

        % Value changed function: tag_trig_signal
        function cb_trig_signal(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_TRIG_SIGNAL=get(handles.tag_trig_signal,'Value')-1;
            
            reset_trigger;
            
            gupdate;
        end

        % Value changed function: tag_trig_type
        function cb_trig_type(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_TRIG_TYPE=get(handles.tag_trig_type,'Value')-1;
            
            reset_trigger;
            
            gupdate;
        end

        % Value changed function: tag_trigger
        function cb_trigger(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            g.QS_TRIGGER=get(handles.tag_trigger,'Value');
            
            if g.QS_TRIGGER==1
            %   turn on trigger
                reset_trigger;
            else
            %   turn off trigger
                tde_gate_m(def.CALL_TDE_TRIGGER_OFF);
            end
            
            gupdate;
        end

        % Value changed function: tag_windowing
        function cb_windowing(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            global g;
            
            def=qs_defines;
            
            istate=get(handles.tag_windowing,'Value');
            
            g.QS_WINDOW=istate-1;
        end

        % Close request function: tag_qlgui
        function tag_qlgui_DeleteFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Stub for DeleteFcn of the figure handles.tag_qlgui.
            
            global g;
            
            g.QS_EXIT=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.tag_qlgui = uifigure('Visible', 'off');
            app.tag_qlgui.CloseRequestFcn = createCallbackFcn(app, @tag_qlgui_DeleteFcn, true);
            app.tag_qlgui.Tag = 'tag_qlgui';
            app.uipanel7 = uipanel(app.tag_qlgui);
            app.uipanel7.Title = 'Measurement settings';
            app.uipanel7.Tag = 'uipanel7';
            app.text29 = uilabel(app.uipanel7);
            app.text29.Tag = 'text29';
            app.text29.Text = 'windowing';
            app.text6 = uilabel(app.uipanel7);
            app.text6.Tag = 'text6';
            app.text6.Text = 'sampling rate';
            app.text7 = uilabel(app.uipanel7);
            app.text7.Tag = 'text7';
            app.text7.Text = 'number of frames';
            app.tag_meas_num = uidropdown(app.uipanel7);
            app.tag_meas_num.Items = {'infinite', '1000', '500', '400', '300', '200', '100', '90', '80', '70', '60', '50', '40', '30', '20', '10', '9', '8', '7', '6', '5', '4', '3', '2', 'single shot'};
            app.tag_meas_num.ValueChangedFcn = createCallbackFcn(app, @cb_meas_num, true);
            app.tag_meas_num.Tag = 'tag_meas_num';
            app.tag_meas_num.Value = 'infinite';
            app.text8 = uilabel(app.uipanel7);
            app.text8.Tag = 'text8';
            app.text8.Text = 'acquisition time';
            app.tag_meas_time = uieditfield(app.uipanel7, 'text');
            app.tag_meas_time.Tag = 'tag_meas_time';
            app.tag_meas_time.Enable = 'off';
            app.tag_meas_time.Value = '0';
            app.text21 = uilabel(app.uipanel7);
            app.text21.Tag = 'text21';
            app.text21.Text = 'frame length';
            app.tag_meas_len = uidropdown(app.uipanel7);
            app.tag_meas_len.Items = {'64', '128', '256', '512', '1024', '2048', '4096', '8192', '16384'};
            app.tag_meas_len.ValueChangedFcn = createCallbackFcn(app, @cb_meas_len, true);
            app.tag_meas_len.Tag = 'tag_meas_len';
            app.tag_meas_len.Value = '64';
            app.checkbox25 = uicheckbox(app.uipanel7);
            app.checkbox25.Tag = 'checkbox25';
            app.checkbox25.Enable = 'off';
            app.checkbox25.Text = 'stream to disk';
            app.pushbutton7 = uibutton(app.uipanel7, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @cb_save_last, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'save last frame';
            app.tag_meas_num_disp = uieditfield(app.uipanel7, 'text');
            app.tag_meas_num_disp.Tag = 'tag_meas_num_disp';
            app.tag_meas_num_disp.Enable = 'off';
            app.tag_meas_num_disp.Value = '0';
            app.text23 = uilabel(app.uipanel7);
            app.text23.Tag = 'text23';
            app.text23.Text = 'current frame';
            app.text24 = uilabel(app.uipanel7);
            app.text24.Tag = 'text24';
            app.text24.Text = 'AA';
            app.tag_aa = uibutton(app.uipanel7, 'state');
            app.tag_aa.ValueChangedFcn = createCallbackFcn(app, @cb_antialiasing, true);
            app.tag_aa.Tag = 'tag_aa';
            app.tag_aa.Text = 'on';
            app.tag_windowing = uidropdown(app.uipanel7);
            app.tag_windowing.Items = {'boxcar', 'von hann'};
            app.tag_windowing.ValueChangedFcn = createCallbackFcn(app, @cb_windowing, true);
            app.tag_windowing.Tag = 'tag_windowing';
            app.tag_windowing.Value = 'boxcar';
            app.tag_meas_rate = uidropdown(app.uipanel7);
            app.tag_meas_rate.Items = {'8192 Hz', '4096 Hz', '2048 Hz', '1024 Hz', '512 Hz', '256 Hz', '128 Hz', '64 Hz', '32 Hz', '16 Hz', '8 Hz', '4 Hz'};
            app.tag_meas_rate.ValueChangedFcn = createCallbackFcn(app, @cb_meas_rate, true);
            app.tag_meas_rate.Tag = 'tag_meas_rate';
            app.tag_meas_rate.Value = '8192 Hz';
            app.uipanel6 = uipanel(app.tag_qlgui);
            app.uipanel6.Title = 'Channel Settings';
            app.uipanel6.Tag = 'uipanel6';
            app.text30 = uilabel(app.uipanel6);
            app.text30.Tag = 'text30';
            app.text30.Text = 'offset';
            app.text25 = uilabel(app.uipanel6);
            app.text25.Tag = 'text25';
            app.text25.Text = 'range';
            app.tag_chan_offset = uislider(app.uipanel6);
            app.tag_chan_offset.Limits = [0 1];
            app.tag_chan_offset.MajorTicks = [];
            app.tag_chan_offset.ValueChangedFcn = createCallbackFcn(app, @cb_chan_offset, true);
            app.tag_chan_offset.MinorTicks = [];
            app.tag_chan_offset.Tag = 'tag_chan_offset';
            app.tag_channel = uibutton(app.uipanel6, 'state');
            app.tag_channel.ValueChangedFcn = createCallbackFcn(app, @cb_channel, true);
            app.tag_channel.Tag = 'tag_channel';
            app.tag_channel.Text = 'on';
            app.text12 = uilabel(app.uipanel6);
            app.text12.Tag = 'text12';
            app.text12.Text = 'signal';
            app.tag_chan_offset_disp = uieditfield(app.uipanel6, 'text');
            app.tag_chan_offset_disp.ValueChangedFcn = createCallbackFcn(app, @cb_chan_offset2, true);
            app.tag_chan_offset_disp.Tag = 'tag_chan_offset_disp';
            app.tag_chan_offset_disp.Value = '0';
            app.tag_chan_range = uidropdown(app.uipanel6);
            app.tag_chan_range.Items = {'+/- 10 V', '+/- 5 V', '+/- 2 V', '+/- 1 V', '+/- 0.5 V', '+/- 0.2 V', '+/- 0.1 V', '+/- 2.5 V', '32-bits counter'};
            app.tag_chan_range.ValueChangedFcn = createCallbackFcn(app, @cb_chan_range, true);
            app.tag_chan_range.Tag = 'tag_chan_range';
            app.tag_chan_range.Value = '+/- 10 V';
            app.tag_chan_zeroise = uibutton(app.uipanel6, 'push');
            app.tag_chan_zeroise.ButtonPushedFcn = createCallbackFcn(app, @cb_chan_zeroise, true);
            app.tag_chan_zeroise.Tag = 'tag_chan_zeroise';
            app.tag_chan_zeroise.Text = 'reset';
            app.tag_chan_signal = uidropdown(app.uipanel6);
            app.tag_chan_signal.Items = {'analog ch 1', 'analog ch 2', 'analog ch 3', 'analog ch 4', 'encoder ch 1', 'encoder ch 2', 'encoder ch 3', 'encoder ch 4'};
            app.tag_chan_signal.ValueChangedFcn = createCallbackFcn(app, @cb_chan_signal, true);
            app.tag_chan_signal.Tag = 'tag_chan_signal';
            app.tag_chan_signal.Value = 'analog ch 1';
            app.text5 = uilabel(app.tag_qlgui);
            app.text5.Tag = 'text5';
            app.text5.Text = 'offset';
            app.tag_inst = uibutton(app.tag_qlgui, 'push');
            app.tag_inst.ButtonPushedFcn = createCallbackFcn(app, @cb_inst, true);
            app.tag_inst.Tag = 'tag_inst';
            app.tag_inst.Text = 'Inst';
            app.tag_avg = uibutton(app.tag_qlgui, 'push');
            app.tag_avg.ButtonPushedFcn = createCallbackFcn(app, @cb_avg, true);
            app.tag_avg.Tag = 'tag_avg';
            app.tag_avg.Text = 'Avg';
            app.tag_stop = uibutton(app.tag_qlgui, 'push');
            app.tag_stop.ButtonPushedFcn = createCallbackFcn(app, @cb_stop, true);
            app.tag_stop.Tag = 'tag_stop';
            app.tag_stop.Text = 'Stop';
            app.tag_exit = uibutton(app.tag_qlgui, 'push');
            app.tag_exit.ButtonPushedFcn = createCallbackFcn(app, @cb_exit, true);
            app.tag_exit.Tag = 'tag_exit';
            app.tag_exit.Text = 'Exit';
            app.pushbutton5 = uibutton(app.tag_qlgui, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @cb_fun_gen, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'signal generator';
            app.tag_ch0 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch0.Tag = 'tag_ch0';
            app.tag_ch0.Enable = 'off';
            app.tag_ch0.Text = '';
            app.tag_ch1 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch1.Tag = 'tag_ch1';
            app.tag_ch1.Enable = 'off';
            app.tag_ch1.Text = '';
            app.tag_ch2 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch2.Tag = 'tag_ch2';
            app.tag_ch2.Enable = 'off';
            app.tag_ch2.Text = '';
            app.tag_ch3 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch3.Tag = 'tag_ch3';
            app.tag_ch3.Enable = 'off';
            app.tag_ch3.Text = '';
            app.text28 = uilabel(app.tag_qlgui);
            app.text28.Tag = 'text28';
            app.text28.Text = 'overload';
            app.text31 = uilabel(app.tag_qlgui);
            app.text31.Tag = 'text31';
            app.text31.Text = 'M.J.G. v.d. Molengraft, 2001-2015';
            app.tag_ch4 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch4.Tag = 'tag_ch4';
            app.tag_ch4.Enable = 'off';
            app.tag_ch4.Text = '';
            app.tag_ch5 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch5.Tag = 'tag_ch5';
            app.tag_ch5.Enable = 'off';
            app.tag_ch5.Text = '';
            app.tag_ch6 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch6.Tag = 'tag_ch6';
            app.tag_ch6.Enable = 'off';
            app.tag_ch6.Text = '';
            app.tag_ch7 = uibutton(app.tag_qlgui, 'state');
            app.tag_ch7.Tag = 'tag_ch7';
            app.tag_ch7.Enable = 'off';
            app.tag_ch7.Text = '';
            app.uipanel9 = uipanel(app.tag_qlgui);
            app.uipanel9.Title = 'Trigger settings';
            app.uipanel9.Tag = 'uipanel9';
            app.text11 = uilabel(app.uipanel9);
            app.text11.Tag = 'text11';
            app.text11.Text = 'level';
            app.text27 = uilabel(app.uipanel9);
            app.text27.Tag = 'text27';
            app.text27.Text = 'low-pass';
            app.text10 = uilabel(app.uipanel9);
            app.text10.Tag = 'text10';
            app.text10.Text = 'type';
            app.tag_trig_relfreq = uidropdown(app.uipanel9);
            app.tag_trig_relfreq.Items = {'off', '40 %', '30 %', '20 %', '10 %', '5 %', '1 %', '0.5 %', '0.1 %'};
            app.tag_trig_relfreq.ValueChangedFcn = createCallbackFcn(app, @cb_trig_relfreq, true);
            app.tag_trig_relfreq.Tag = 'tag_trig_relfreq';
            app.tag_trig_relfreq.Value = 'off';
            app.tag_trig_level = uidropdown(app.uipanel9);
            app.tag_trig_level.Items = {'100 %', '50 %', '25 %', '10 % ', '5 %', '1 %', '0.1 %', '0 %', '-0.1 %', '-1 %', '-5 %', '-10 %', '-25 %', '-50 %', '-100 %'};
            app.tag_trig_level.ValueChangedFcn = createCallbackFcn(app, @cb_trig_level, true);
            app.tag_trig_level.Tag = 'tag_trig_level';
            app.tag_trig_level.Value = '100 %';
            app.tag_trig_type = uidropdown(app.uipanel9);
            app.tag_trig_type.Items = {'positive slope', 'negative slope'};
            app.tag_trig_type.ValueChangedFcn = createCallbackFcn(app, @cb_trig_type, true);
            app.tag_trig_type.Tag = 'tag_trig_type';
            app.tag_trig_type.Value = 'positive slope';
            app.text20 = uilabel(app.uipanel9);
            app.text20.Tag = 'text20';
            app.text20.Text = 'pre-roll';
            app.text13 = uilabel(app.uipanel9);
            app.text13.Tag = 'text13';
            app.text13.Text = 'signal';
            app.tag_trigger = uibutton(app.uipanel9, 'state');
            app.tag_trigger.ValueChangedFcn = createCallbackFcn(app, @cb_trigger, true);
            app.tag_trigger.Tag = 'tag_trigger';
            app.tag_trigger.Text = 'on';
            app.tag_trig_preroll = uidropdown(app.uipanel9);
            app.tag_trig_preroll.Items = {'0 %', '5 %', '10 %', '20 %', '30 %', '40 %', '50 %'};
            app.tag_trig_preroll.ValueChangedFcn = createCallbackFcn(app, @cb_trig_preroll, true);
            app.tag_trig_preroll.Tag = 'tag_trig_preroll';
            app.tag_trig_preroll.Value = '0 %';
            app.tag_trig_signal = uidropdown(app.uipanel9);
            app.tag_trig_signal.Items = {'analog ch 1', 'analog ch 2', 'analog ch 3', 'analog ch 4', ''};
            app.tag_trig_signal.ValueChangedFcn = createCallbackFcn(app, @cb_trig_signal, true);
            app.tag_trig_signal.Tag = 'tag_trig_signal';
            app.tag_trig_signal.Value = 'analog ch 1';
            app.uipanel8 = uipanel(app.tag_qlgui);
            app.uipanel8.Title = 'Scope settings';
            app.uipanel8.Tag = 'uipanel8';
            app.text15 = uilabel(app.uipanel8);
            app.text15.Tag = 'text15';
            app.text15.Text = 'type';
            app.text14 = uilabel(app.uipanel8);
            app.text14.Tag = 'text14';
            app.text14.Text = 'scope';
            app.tag_scope_type = uidropdown(app.uipanel8);
            app.tag_scope_type.Items = {'time', 'fft: modulus', 'fft: phase', 'auto esd', 'auto psd', 'cumul. auto psd', 'auto ps', 'cross psd', 'frf: modulus', 'frf: phase', 'coherence'};
            app.tag_scope_type.ValueChangedFcn = createCallbackFcn(app, @cb_scope_type, true);
            app.tag_scope_type.Tag = 'tag_scope_type';
            app.tag_scope_type.Value = 'time';
            app.tag_scope_adc_ch0 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch0.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch0.Tag = 'tag_scope_adc_ch0';
            app.tag_scope_adc_ch0.Text = 'ch 1';
            app.tag_scope_adc_ch1 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch1.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch1.Tag = 'tag_scope_adc_ch1';
            app.tag_scope_adc_ch1.Text = 'ch 2';
            app.tag_scope_adc_ch2 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch2.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch2.Tag = 'tag_scope_adc_ch2';
            app.tag_scope_adc_ch2.Text = 'ch 3';
            app.tag_scope_adc_ch3 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch3.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch3.Tag = 'tag_scope_adc_ch3';
            app.tag_scope_adc_ch3.Text = 'ch 4';
            app.tag_scope_id = uidropdown(app.uipanel8);
            app.tag_scope_id.Items = {'scope 1', 'scope 2'};
            app.tag_scope_id.ValueChangedFcn = createCallbackFcn(app, @cb_scope_id, true);
            app.tag_scope_id.Tag = 'tag_scope_id';
            app.tag_scope_id.Value = 'scope 1';
            app.tag_scope_mem = uibutton(app.uipanel8, 'state');
            app.tag_scope_mem.ValueChangedFcn = createCallbackFcn(app, @cb_scope_mem, true);
            app.tag_scope_mem.Tag = 'tag_scope_mem';
            app.tag_scope_mem.Text = 'hold';
            app.text16 = uilabel(app.uipanel8);
            app.text16.Tag = 'text16';
            app.text16.Text = 'scale';
            app.tag_scope_enc_ch0 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch0.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch0.Tag = 'tag_scope_enc_ch0';
            app.tag_scope_enc_ch0.Text = 'ch 1';
            app.tag_scope_enc_ch1 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch1.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch1.Tag = 'tag_scope_enc_ch1';
            app.tag_scope_enc_ch1.Text = 'ch 2';
            app.tag_scope_enc_ch2 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch2.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch2.Tag = 'tag_scope_enc_ch2';
            app.tag_scope_enc_ch2.Text = 'ch 3';
            app.tag_scope_enc_ch3 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch3.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch3.Tag = 'tag_scope_enc_ch3';
            app.tag_scope_enc_ch3.Text = 'ch 4';
            app.tag_scope_freeze = uibutton(app.uipanel8, 'state');
            app.tag_scope_freeze.ValueChangedFcn = createCallbackFcn(app, @cb_scope_freeze, true);
            app.tag_scope_freeze.Tag = 'tag_scope_freeze';
            app.tag_scope_freeze.Text = 'freeze';
            app.tag_scope_adc_ch4 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch4.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch4.Tag = 'tag_scope_adc_ch4';
            app.tag_scope_adc_ch4.Text = 'ch 5';
            app.tag_scope_adc_ch5 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch5.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch5.Tag = 'tag_scope_adc_ch5';
            app.tag_scope_adc_ch5.Text = 'ch 6';
            app.tag_scope_enc_ch4 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch4.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch4.Tag = 'tag_scope_enc_ch4';
            app.tag_scope_enc_ch4.Text = 'ch 5';
            app.tag_scope_enc_ch5 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch5.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch5.Tag = 'tag_scope_enc_ch5';
            app.tag_scope_enc_ch5.Text = 'ch 6';
            app.tag_scope_adc_ch6 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch6.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch6.Tag = 'tag_scope_adc_ch6';
            app.tag_scope_adc_ch6.Text = 'ch 7';
            app.tag_scope_adc_ch7 = uicheckbox(app.uipanel8);
            app.tag_scope_adc_ch7.ValueChangedFcn = createCallbackFcn(app, @cb_scope_adc, true);
            app.tag_scope_adc_ch7.Tag = 'tag_scope_adc_ch7';
            app.tag_scope_adc_ch7.Text = 'ch 8';
            app.tag_scope_enc_ch6 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch6.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch6.Tag = 'tag_scope_enc_ch6';
            app.tag_scope_enc_ch6.Text = 'ch 7';
            app.tag_scope_enc_ch7 = uicheckbox(app.uipanel8);
            app.tag_scope_enc_ch7.ValueChangedFcn = createCallbackFcn(app, @cb_scope_enc, true);
            app.tag_scope_enc_ch7.Tag = 'tag_scope_enc_ch7';
            app.tag_scope_enc_ch7.Text = 'ch 8';
            app.text32 = uilabel(app.uipanel8);
            app.text32.Tag = 'text32';
            app.text32.Text = 'analog in';
            app.text33 = uilabel(app.uipanel8);
            app.text33.Tag = 'text33';
            app.text33.Text = 'encoder in';
            app.tag_scope_scale = uidropdown(app.uipanel8);
            app.tag_scope_scale.Items = {'linear', 'log'};
            app.tag_scope_scale.ValueChangedFcn = createCallbackFcn(app, @cb_scope_scale, true);
            app.tag_scope_scale.Tag = 'tag_scope_scale';
            app.tag_scope_scale.Value = 'linear';
            app.uipanel10 = uipanel(app.tag_qlgui);
            app.uipanel10.Title = 'Settings';
            app.uipanel10.Tag = 'uipanel10';
            app.pushbutton13 = uibutton(app.uipanel10, 'push');
            app.pushbutton13.ButtonPushedFcn = createCallbackFcn(app, @cb_save, true);
            app.pushbutton13.Tag = 'pushbutton13';
            app.pushbutton13.Icon = 'pushbutton13_image.png';
            app.pushbutton13.Text = '';
            app.pushbutton14 = uibutton(app.uipanel10, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @cb_load, true);
            app.pushbutton14.Tag = 'pushbutton14';
            app.pushbutton14.Icon = 'pushbutton14_image.png';
            app.pushbutton14.Text = '';
            app.TextAreaLabel = uilabel(app.tag_qlgui);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.tag_qlgui);
            app.TextArea.Value = {'RTScope'};
            % Show the figure after all components are created
            app.tag_qlgui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qlgui_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.tag_qlgui)
            else

                % Focus the running singleton app
                figure(runningApp.tag_qlgui)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.tag_qlgui)
        end
    end
end